classdef UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        EpilepsiaeSeizuresPredictorandDetectorLabel  matlab.ui.control.Label
        DetectionLamp                 matlab.ui.control.Lamp
        PredictionLamp                matlab.ui.control.Lamp
        DetectionButton               matlab.ui.control.Button
        PredictionButton              matlab.ui.control.Button
        ConfigureNeuralNetworksPanel  matlab.ui.container.Panel
        RetrainNNLamp                 matlab.ui.control.Lamp
        withthesameparametersNNLabel  matlab.ui.control.Label
        RetrainNNButton               matlab.ui.control.Button
        UseautoencoderSwitchLabel     matlab.ui.control.Label
        UseautoencoderSwitch          matlab.ui.control.Switch
        AutoencoderPanel              matlab.ui.container.Panel
        RetrainAELamp                 matlab.ui.control.Lamp
        withthesameparametersAELabel  matlab.ui.control.Label
        RetrainAEButton               matlab.ui.control.Button
        AutoencoderChooseFileButton   matlab.ui.control.Button
        IconInfoAutoencoder           matlab.ui.control.Image
        AutoencoderLabel              matlab.ui.control.Label
        IconInfoNN                    matlab.ui.control.Image
        NNChooseFileButton            matlab.ui.control.Button
        ShallowDeepNNLabel            matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        MiguelAntnioGabrieldeAlmeidaFaria2019216809Label  matlab.ui.control.Label
        SanchoAmaralSimes2019217590Label  matlab.ui.control.Label
        MEISIMachineLearning20222023Label  matlab.ui.control.Label
        UniversityofCoimbraLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        nnFile = "";
        autoencoderFile = "";
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PredictionButton.Enable = "off";
            app.DetectionButton.Enable = "off";
            app.PredictionLamp.Color = "red";
            app.DetectionLamp.Color = "red";
            app.RetrainNNButton.Enable = "off";
            app.RetrainNNLamp.Color = "red";
            app.RetrainAEButton.Enable = "off";
            app.RetrainAELamp.Color = "red";
            app.IconInfoNN.Tooltip = "info";
            app.IconInfoAutoencoder.Tooltip = "info";
            app.AutoencoderPanel.Visible = "off";
            
        end

        % Button pushed function: PredictionButton
        function PredictionButtonPushed(app, event)
            
        end

        % Button pushed function: DetectionButton
        function DetectionButtonPushed(app, event)
            
        end

        % Button pushed function: RetrainNNButton
        function RetrainNNButtonPushed(app, event)
            
        end

        % Value changed function: UseautoencoderSwitch
        function UseautoencoderSwitchValueChanged(app, event)
            value = app.UseautoencoderSwitch.Value;
            
            if strcmpi(value,'No')
                app.AutoencoderPanel.Visible = "off";

            elseif strcmpi(value,'Yes')
                app.AutoencoderPanel.Visible = "on";

            end
        end

        % Button pushed function: NNChooseFileButton
        function NNChooseFileButtonPushed(app, event)
            [fileName, filePath] = uigetfile("*.mat");

            totalPath = string(filePath) + string(fileName);
            if isfile(totalPath)
                app.nnFile = string(filePath) + string(fileName);
                %ESPStatic.setGetNNPath(app.nnFile);
                app.NNChooseFileButton.Text = "Choose file - " + fileName;
                if app.nnFile ~= ""
                    app.PredictionButton.Enable = "on";
                    app.DetectionButton.Enable = "on";
                    app.RetrainNNButton.Enable = "on";
                    app.PredictionLamp.Color = "green";
                    app.DetectionLamp.Color = "green";
                    app.RetrainNNLamp.Color = "green";
                end
            end
        end

        % Button pushed function: AutoencoderChooseFileButton
        function AutoencoderChooseFileButtonPushed(app, event)
            [fileName, filePath] = uigetfile("*.mat");

            totalPath = string(filePath) + string(fileName);
            if isfile(totalPath)
                app.autoencoderFile = string(filePath) + string(fileName);
                %ESPStatic.setGetAutoencoderPath(app.autoencoderFile);
                app.AutoencoderChooseFileButton.Text = "Choose file - " + fileName;
                if app.autoencoderFile ~= ""
                    app.RetrainAEButton.Enable = "on";
                    app.RetrainAELamp.Color = "green";
                end
            end
        end

        % Button pushed function: RetrainAEButton
        function RetrainAEButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.UniversityofCoimbraLabel = uilabel(app.Panel);
            app.UniversityofCoimbraLabel.Text = 'University of Coimbra';
            app.MEISIMachineLearning20222023Label = uilabel(app.Panel);
            app.MEISIMachineLearning20222023Label.Text = 'MEI/SI/Machine Learning - 2022/2023';
            app.SanchoAmaralSimes2019217590Label = uilabel(app.Panel);
            app.SanchoAmaralSimes2019217590Label.Text = 'Sancho Amaral Simões, 2019217590';
            app.MiguelAntnioGabrieldeAlmeidaFaria2019216809Label = uilabel(app.Panel);
            app.MiguelAntnioGabrieldeAlmeidaFaria2019216809Label.Text = 'Miguel António Gabriel de Almeida Faria, 2019216809';
            app.ConfigureNeuralNetworksPanel = uipanel(app.UIFigure);
            app.ConfigureNeuralNetworksPanel.Title = 'Configure Neural Networks';
            app.ShallowDeepNNLabel = uilabel(app.ConfigureNeuralNetworksPanel);
            app.ShallowDeepNNLabel.Text = 'Shallow/Deep NN';
            app.NNChooseFileButton = uibutton(app.ConfigureNeuralNetworksPanel, 'push');
            app.NNChooseFileButton.ButtonPushedFcn = createCallbackFcn(app, @NNChooseFileButtonPushed, true);
            app.NNChooseFileButton.Text = 'Choose File - Empty';
            app.IconInfoNN = uiimage(app.ConfigureNeuralNetworksPanel);
            app.IconInfoNN.ImageSource = fullfile(pathToMLAPP, 'info_icon.png');
            app.AutoencoderPanel = uipanel(app.ConfigureNeuralNetworksPanel);
            app.AutoencoderLabel = uilabel(app.AutoencoderPanel);
            app.AutoencoderLabel.Text = 'Autoencoder';
            app.IconInfoAutoencoder = uiimage(app.AutoencoderPanel);
            app.IconInfoAutoencoder.ImageSource = fullfile(pathToMLAPP, 'info_icon.png');
            app.AutoencoderChooseFileButton = uibutton(app.AutoencoderPanel, 'push');
            app.AutoencoderChooseFileButton.ButtonPushedFcn = createCallbackFcn(app, @AutoencoderChooseFileButtonPushed, true);
            app.AutoencoderChooseFileButton.Text = 'Choose File - Empty';
            app.RetrainAEButton = uibutton(app.AutoencoderPanel, 'push');
            app.RetrainAEButton.ButtonPushedFcn = createCallbackFcn(app, @RetrainAEButtonPushed, true);
            app.RetrainAEButton.Text = 'Retrain AE';
            app.withthesameparametersAELabel = uilabel(app.AutoencoderPanel);
            app.withthesameparametersAELabel.Text = '(with the same parameters)';
            app.RetrainAELamp = uilamp(app.AutoencoderPanel);
            app.UseautoencoderSwitch = uiswitch(app.ConfigureNeuralNetworksPanel, 'slider');
            app.UseautoencoderSwitch.Items = {'No', 'Yes'};
            app.UseautoencoderSwitch.ValueChangedFcn = createCallbackFcn(app, @UseautoencoderSwitchValueChanged, true);
            app.UseautoencoderSwitch.Value = 'No';
            app.UseautoencoderSwitchLabel = uilabel(app.ConfigureNeuralNetworksPanel);
            app.UseautoencoderSwitchLabel.Text = 'Use autoencoder';
            app.RetrainNNButton = uibutton(app.ConfigureNeuralNetworksPanel, 'push');
            app.RetrainNNButton.ButtonPushedFcn = createCallbackFcn(app, @RetrainNNButtonPushed, true);
            app.RetrainNNButton.Text = 'Retrain NN';
            app.withthesameparametersNNLabel = uilabel(app.ConfigureNeuralNetworksPanel);
            app.withthesameparametersNNLabel.Text = '(with the same parameters)';
            app.RetrainNNLamp = uilamp(app.ConfigureNeuralNetworksPanel);
            app.PredictionButton = uibutton(app.UIFigure, 'push');
            app.PredictionButton.ButtonPushedFcn = createCallbackFcn(app, @PredictionButtonPushed, true);
            app.PredictionButton.Text = 'Prediction';
            app.DetectionButton = uibutton(app.UIFigure, 'push');
            app.DetectionButton.ButtonPushedFcn = createCallbackFcn(app, @DetectionButtonPushed, true);
            app.DetectionButton.Text = 'Detection';
            app.PredictionLamp = uilamp(app.UIFigure);
            app.DetectionLamp = uilamp(app.UIFigure);
            app.EpilepsiaeSeizuresPredictorandDetectorLabel = uilabel(app.UIFigure);
            app.EpilepsiaeSeizuresPredictorandDetectorLabel.Text = 'Epilepsiae Seizures Predictor and Detector';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end